classdef ecualizador < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        BrillianceLabel       matlab.ui.control.Label
        brilliance            matlab.ui.control.Slider
        PresenceLabel         matlab.ui.control.Label
        presence              matlab.ui.control.Slider
        HighMidsLabel         matlab.ui.control.Label
        highmids              matlab.ui.control.Slider
        LowMidsLabel          matlab.ui.control.Label
        lowmids               matlab.ui.control.Slider
        BassLabel             matlab.ui.control.Label
        bass                  matlab.ui.control.Slider
        SubbassLabel          matlab.ui.control.Label
        subbass               matlab.ui.control.Slider
        ButtonGroup           matlab.ui.container.ButtonGroup
        expansion             matlab.ui.control.RadioButton
        decimacion            matlab.ui.control.RadioButton
        FactorLabel           matlab.ui.control.Label
        factor                matlab.ui.control.Spinner
        EjecutarButton        matlab.ui.control.Button
        brilliancefrecuencia  matlab.ui.control.UIAxes
        presencefrecuencia    matlab.ui.control.UIAxes
        highmidsfrecuencia    matlab.ui.control.UIAxes
        lowmidsfrecuencia     matlab.ui.control.UIAxes
        bassfrecuencia        matlab.ui.control.UIAxes
        subbassfrecuencia     matlab.ui.control.UIAxes
        brilliancetiempo      matlab.ui.control.UIAxes
        presencetiempo        matlab.ui.control.UIAxes
        highmidstiempo        matlab.ui.control.UIAxes
        lowmidstiempo         matlab.ui.control.UIAxes
        basstiempo            matlab.ui.control.UIAxes
        subbasstiempo         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EjecutarButton
        function EjecutarButtonPushed(app, event)
            clc;
    close all;
N = app.factor.Value;
%% Señal Original x[n]
name_a="counting_stars_cut.wav";
%Informacion del audio original

info_x = audioinfo(name_a)


[audio, fs] = audioread(name_a);

%%
audio = 0.5*(audio(:, 1) + audio(:, 2)).';  %%Audio estereo a mono

duracion_audio = length(audio) / fs;   %%duración

n_samples = length(audio);  %%num_muestras


%%
vec_xt = 0:seconds(1/fs): seconds(duracion_audio);
vec_xt = vec_xt(1:end-1);

figure(1)
subplot(3,1,1);
plot(vec_xt,transpose(audio));
title("Audio x(t)");
xlabel("Tiempo");
ylabel("Amplitud");
grid on;
grid minor;

figure(1)
subplot(3,1,2);
vec_nx = 1:n_samples;
stem(vec_nx, audio);
title("Audio x[n]");
xlabel("Tiempo (Solo un Intervalo de muestras)");
%xlim([500000 500100])
ylabel("Amplitud");
grid on;
grid minor;


%% Transformada de Fourier del audio original
X = fft(audio, n_samples)/n_samples;  
Xi = fftshift(X);

frec_X = linspace(-fs/2, fs/2, length(X));

figure(1);
subplot(3,1,3);
plot(frec_X, 2*abs(Xi));
title("Espectro de frecuencias de x[n]: $X(e^{j\omega})$", 'Interpreter', 'latex');
xlabel("frecuencia [Hz]");
ylabel("Amplitud");

grid on;
grid minor;

A = app.bass.Value;
B = app.subbass.Value;

%% Decimación

M = N;
% Filtro Anti-aliasing
Hdec_LPF = abs(frec_X)<= (fs/(2*M));

% Filtrado para decimación
X_filtered = (Xi .* Hdec_LPF);

x_filt = ifft(X_filtered*n_samples); 

%x_filt = real(x_filt);

% Operacion de decimación
f_dec = fs / M;
muestras_dec = n_samples / M;

y_dec = zeros;
for n = 1:muestras_dec
    y_dec(n) = (x_filt(n*M));
end

%%%FFT de la señal diezmada
Y_dec = fft(y_dec)/round(muestras_dec);
Yi_dec = fftshift(Y_dec);

frecY_dec = linspace(-fs/2, fs/2, muestras_dec);


%%% Graficas de decimación

figure(2);
subplot(4,1,1);
plot(frec_X, abs(X_filtered)/max(abs(X_filtered)));
hold on;
plot(frec_X, Hdec_LPF,'r');
title("Espectro (normalizado) de X por el filtro anti aliasing $X(e^{j\omega}) . H(e^{j\omega})$", 'Interpreter', 'latex');
xlabel("Frecuencia [Hz]");
ylabel("Amplitud");
ylim([-0.1 1.1])
grid on; grid minor;
ax = gca;

tdec = 0:seconds(1/f_dec): seconds(duracion_audio);
tdec = tdec(1:end-1);
figure(2)
subplot(4,1,2);
plot(tdec, real(y_dec*(-1)));
title("Señal x(t) diezmada");
xlabel("Tiempo");
ylabel("Amplitud");
grid on;
grid minor;

figure(2)
subplot(4,1,3);
nx_dec = 1:muestras_dec;
stem(nx_dec, real(y_dec*(-1)));
title("Señal x[n] diezmada");
xlabel("Tiempo (Solo un Intervalo de muestras)");
%xlim([500000 500100]);
ylabel("Amplitud");
grid on;
grid minor;

figure(2);
subplot(4,1,4);
plot(frecY_dec, 2*abs(Yi_dec));
title("Espectro de frecuencias de la señal diezmada");
xlabel("frecuencia [Hz]");
ylabel("Amplitud");

%%%%%%

%% Expansión / Interpolación 

L = N;  %%factor de interpolación 

f_interp = fs*L;
muestras_interp = n_samples*L;

%%% Operación de interpolación 

y_expand = zeros;

for n = 1:muestras_interp
    if mod(n,L) == 0
        y_expand(n) = audio(n/L);
    else
        y_expand(n) = 0;
    end
end


%%% FFT de la señal expandida y filtrado
Y_expand = fft(y_expand, muestras_interp)/muestras_interp;  

Yi_expand = fftshift(Y_expand);

frecY_expand = linspace(-fs/2, fs/2, muestras_interp);

%%% Filtro de interpolación
Hexp_LPF = abs(frecY_expand) <= (fs/L)*(0.5);

Yexp_filt = Yi_expand .* Hexp_LPF;


figure(3);
subplot(4,1,1);
plot(frecY_expand, 2*abs(Yi_expand));
title("Espectro de frecuencias la señal expandida");
xlabel("frecuencia [Hz]");
ylabel("Amplitud");

figure(3);
subplot(4,1,2);
plot(frecY_expand, Hexp_LPF, 'r');
hold on;
plot(frecY_expand, abs(Yexp_filt)/max(abs(Yexp_filt)), 'b');
title("Espectro de la señal expandida y filtrada");
xlabel("frecuencia [Hz]");
ylabel("Amplitud");


%% extrapolacion en el tiempo;
yexp_filtered = ifft(fftshift(Yexp_filt*muestras_interp));

texp = 0:seconds(1/f_interp): seconds(duracion_audio);
texp = texp(1:length(yexp_filtered));

figure(3)
subplot(4,1,3);
plot(texp, real(yexp_filtered));
title("Señal x(t) Expandida");
xlabel("Tiempo");
ylabel("Amplitud");
grid on;
grid minor;

figure(3)
subplot(4,1,4);
ny_exp = 1:muestras_interp;
stem(ny_exp, real(yexp_filtered));
title("Señal x[n] expandida");
xlabel("Tiempo (Solo un Intervalo de muestras)");
%xlim([500000 500100]);
ylabel("Amplitud");
grid on;
grid minor;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.subbasstiempo = uiaxes(app.UIFigure);
            title(app.subbasstiempo, 'Title')
            xlabel(app.subbasstiempo, 'X')
            ylabel(app.subbasstiempo, 'Y')
            zlabel(app.subbasstiempo, 'Z')
            app.basstiempo = uiaxes(app.UIFigure);
            title(app.basstiempo, 'Title')
            xlabel(app.basstiempo, 'X')
            ylabel(app.basstiempo, 'Y')
            zlabel(app.basstiempo, 'Z')
            app.lowmidstiempo = uiaxes(app.UIFigure);
            title(app.lowmidstiempo, 'Title')
            xlabel(app.lowmidstiempo, 'X')
            ylabel(app.lowmidstiempo, 'Y')
            zlabel(app.lowmidstiempo, 'Z')
            app.highmidstiempo = uiaxes(app.UIFigure);
            title(app.highmidstiempo, 'Title')
            xlabel(app.highmidstiempo, 'X')
            ylabel(app.highmidstiempo, 'Y')
            zlabel(app.highmidstiempo, 'Z')
            app.presencetiempo = uiaxes(app.UIFigure);
            title(app.presencetiempo, 'Title')
            xlabel(app.presencetiempo, 'X')
            ylabel(app.presencetiempo, 'Y')
            zlabel(app.presencetiempo, 'Z')
            app.brilliancetiempo = uiaxes(app.UIFigure);
            title(app.brilliancetiempo, 'Title')
            xlabel(app.brilliancetiempo, 'X')
            ylabel(app.brilliancetiempo, 'Y')
            zlabel(app.brilliancetiempo, 'Z')
            app.subbassfrecuencia = uiaxes(app.UIFigure);
            title(app.subbassfrecuencia, 'Title')
            xlabel(app.subbassfrecuencia, 'X')
            ylabel(app.subbassfrecuencia, 'Y')
            zlabel(app.subbassfrecuencia, 'Z')
            app.bassfrecuencia = uiaxes(app.UIFigure);
            title(app.bassfrecuencia, 'Title')
            xlabel(app.bassfrecuencia, 'X')
            ylabel(app.bassfrecuencia, 'Y')
            zlabel(app.bassfrecuencia, 'Z')
            app.lowmidsfrecuencia = uiaxes(app.UIFigure);
            title(app.lowmidsfrecuencia, 'Title')
            xlabel(app.lowmidsfrecuencia, 'X')
            ylabel(app.lowmidsfrecuencia, 'Y')
            zlabel(app.lowmidsfrecuencia, 'Z')
            app.highmidsfrecuencia = uiaxes(app.UIFigure);
            title(app.highmidsfrecuencia, 'Title')
            xlabel(app.highmidsfrecuencia, 'X')
            ylabel(app.highmidsfrecuencia, 'Y')
            zlabel(app.highmidsfrecuencia, 'Z')
            app.presencefrecuencia = uiaxes(app.UIFigure);
            title(app.presencefrecuencia, 'Title')
            xlabel(app.presencefrecuencia, 'X')
            ylabel(app.presencefrecuencia, 'Y')
            zlabel(app.presencefrecuencia, 'Z')
            app.brilliancefrecuencia = uiaxes(app.UIFigure);
            title(app.brilliancefrecuencia, 'Title')
            xlabel(app.brilliancefrecuencia, 'X')
            ylabel(app.brilliancefrecuencia, 'Y')
            zlabel(app.brilliancefrecuencia, 'Z')
            app.EjecutarButton = uibutton(app.UIFigure, 'push');
            app.EjecutarButton.ButtonPushedFcn = createCallbackFcn(app, @EjecutarButtonPushed, true);
            app.EjecutarButton.Text = 'Ejecutar';
            app.factor = uispinner(app.UIFigure);
            app.factor.Step = 2;
            app.factor.Limits = [2 10];
            app.factor.Value = 2;
            app.FactorLabel = uilabel(app.UIFigure);
            app.FactorLabel.Text = 'Factor ';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.decimacion = uiradiobutton(app.ButtonGroup);
            app.decimacion.Text = 'Decimación';
            app.decimacion.Value = true;
            app.expansion = uiradiobutton(app.ButtonGroup);
            app.expansion.Text = 'Expansión';
            app.subbass = uislider(app.UIFigure);
            app.subbass.Limits = [-50 50];
            app.SubbassLabel = uilabel(app.UIFigure);
            app.SubbassLabel.Text = 'Sub-bass';
            app.bass = uislider(app.UIFigure);
            app.bass.Limits = [-50 50];
            app.BassLabel = uilabel(app.UIFigure);
            app.BassLabel.Text = 'Bass';
            app.lowmids = uislider(app.UIFigure);
            app.lowmids.Limits = [-50 50];
            app.LowMidsLabel = uilabel(app.UIFigure);
            app.LowMidsLabel.Text = 'Low Mids';
            app.highmids = uislider(app.UIFigure);
            app.highmids.Limits = [-50 50];
            app.HighMidsLabel = uilabel(app.UIFigure);
            app.HighMidsLabel.Text = 'High Mids';
            app.presence = uislider(app.UIFigure);
            app.presence.Limits = [-50 50];
            app.PresenceLabel = uilabel(app.UIFigure);
            app.PresenceLabel.Text = 'Presence';
            app.brilliance = uislider(app.UIFigure);
            app.brilliance.Limits = [-50 50];
            app.BrillianceLabel = uilabel(app.UIFigure);
            app.BrillianceLabel.Text = 'Brilliance';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ecualizador

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end